classdef Componente_Practico < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        AmpsLabel_2        matlab.ui.control.Label
        AmpsLabel          matlab.ui.control.Label
        VLabel_5           matlab.ui.control.Label
        VLabel_4           matlab.ui.control.Label
        VLabel_3           matlab.ui.control.Label
        VLabel_2           matlab.ui.control.Label
        I2EditField        matlab.ui.control.NumericEditField
        I2EditFieldLabel   matlab.ui.control.Label
        I1EditField        matlab.ui.control.NumericEditField
        I1EditFieldLabel   matlab.ui.control.Label
        VR4EditField       matlab.ui.control.NumericEditField
        VR4EditFieldLabel  matlab.ui.control.Label
        VR2EditField       matlab.ui.control.NumericEditField
        VR2EditFieldLabel  matlab.ui.control.Label
        VR3EditField       matlab.ui.control.NumericEditField
        VR3EditFieldLabel  matlab.ui.control.Label
        VR1EditField       matlab.ui.control.NumericEditField
        VR1EditFieldLabel  matlab.ui.control.Label
        RubnDaroDeLaPuenteCastroGrupo203036_111Label  matlab.ui.control.Label
        Label_4            matlab.ui.control.Label
        Label_3            matlab.ui.control.Label
        Label_2            matlab.ui.control.Label
        Label              matlab.ui.control.Label
        VLabel             matlab.ui.control.Label
        R3EditField        matlab.ui.control.NumericEditField
        R3EditFieldLabel   matlab.ui.control.Label
        R4EditField        matlab.ui.control.NumericEditField
        R4EditFieldLabel   matlab.ui.control.Label
        VinEditField       matlab.ui.control.NumericEditField
        VinEditFieldLabel  matlab.ui.control.Label
        R2EditField        matlab.ui.control.NumericEditField
        R2EditFieldLabel   matlab.ui.control.Label
        R1EditField        matlab.ui.control.NumericEditField
        R1EditFieldLabel   matlab.ui.control.Label
        OUTPUTLabel        matlab.ui.control.Label
        INPUTLabel         matlab.ui.control.Label
        CalcularButton     matlab.ui.control.Button
        Image3             matlab.ui.control.Image
        Image2             matlab.ui.control.Image
        Image              matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            % 1. Obtener los valores de los campos de entrada (INPUTS)
            vin = app.VinEditField.Value;
            r1 = app.R1EditField.Value;
            r2 = app.R2EditField.Value;
            r3 = app.R3EditField.Value;
            r4 = app.R4EditField.Value;
            
            % Validación simple para evitar división por cero
            if (r2 + r3) == 0
                uialert(app.UIFigure, 'La suma de R2 y R3 no puede ser cero.', 'Error de cálculo');
                return;
            end

            % 2. Realizar los cálculos matemáticos (Según la guía)
            
            % Resistencia del paralelo (R2 || R3)
            r_p = (r2 * r3) / (r2 + r3);
            
            % Resistencia Total
            r_total = r1 + r_p + r4;
            
            if r_total == 0
                 uialert(app.UIFigure, 'La resistencia total es cero.', 'Error');
                 return;
            end

            % Corriente Total (I1)
            i1 = vin / r_total;
            
            % Voltajes en las resistencias serie
            vr1 = i1 * r1;
            vr4 = i1 * r4;
            
            % Voltaje en el paralelo (es el mismo para R2 y R3)
            v_p = vin - (vr1 + vr4);
            vr2 = v_p;
            vr3 = v_p;
            
            % Corriente I2 (la que pasa por la rama de R3)
            if r3 > 0
                i2 = v_p / r3;
            else
                i2 = 0;
            end

            % 3. Mostrar los resultados en los campos de salida (OUTPUTS)
            app.I1EditField.Value = i1;
            app.I2EditField.Value = i2;
            
            app.VR1EditField.Value = vr1;
            app.VR2EditField.Value = vr2;
            app.VR3EditField.Value = vr3;
            app.VR4EditField.Value = vr4;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Circuito.PNG');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'LeyOhnm.PNG');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'ley_kirchhoff.PNG');
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.INPUTLabel = uilabel(app.UIFigure);
            app.INPUTLabel.Text = 'INPUT';
            app.OUTPUTLabel = uilabel(app.UIFigure);
            app.OUTPUTLabel.Text = 'OUTPUT';
            app.R1EditFieldLabel = uilabel(app.UIFigure);
            app.R1EditFieldLabel.Text = 'R1';
            app.R1EditField = uieditfield(app.UIFigure, 'numeric');
            app.R2EditFieldLabel = uilabel(app.UIFigure);
            app.R2EditFieldLabel.Text = 'R2';
            app.R2EditField = uieditfield(app.UIFigure, 'numeric');
            app.VinEditFieldLabel = uilabel(app.UIFigure);
            app.VinEditFieldLabel.Text = 'Vin';
            app.VinEditField = uieditfield(app.UIFigure, 'numeric');
            app.R4EditFieldLabel = uilabel(app.UIFigure);
            app.R4EditFieldLabel.Text = 'R4';
            app.R4EditField = uieditfield(app.UIFigure, 'numeric');
            app.R3EditFieldLabel = uilabel(app.UIFigure);
            app.R3EditFieldLabel.Text = 'R3';
            app.R3EditField = uieditfield(app.UIFigure, 'numeric');
            app.VLabel = uilabel(app.UIFigure);
            app.VLabel.Text = 'V';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Ω';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Ω';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Ω';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'Ω';
            app.RubnDaroDeLaPuenteCastroGrupo203036_111Label = uilabel(app.UIFigure);
            app.RubnDaroDeLaPuenteCastroGrupo203036_111Label.Text = 'Rubén Darío De La Puente Castro - Grupo: 203036_111 ';
            app.VR1EditFieldLabel = uilabel(app.UIFigure);
            app.VR1EditFieldLabel.Text = 'VR1';
            app.VR1EditField = uieditfield(app.UIFigure, 'numeric');
            app.VR3EditFieldLabel = uilabel(app.UIFigure);
            app.VR3EditFieldLabel.Text = 'VR3';
            app.VR3EditField = uieditfield(app.UIFigure, 'numeric');
            app.VR2EditFieldLabel = uilabel(app.UIFigure);
            app.VR2EditFieldLabel.Text = 'VR2';
            app.VR2EditField = uieditfield(app.UIFigure, 'numeric');
            app.VR4EditFieldLabel = uilabel(app.UIFigure);
            app.VR4EditFieldLabel.Text = 'VR4';
            app.VR4EditField = uieditfield(app.UIFigure, 'numeric');
            app.I1EditFieldLabel = uilabel(app.UIFigure);
            app.I1EditFieldLabel.Text = 'I1';
            app.I1EditField = uieditfield(app.UIFigure, 'numeric');
            app.I2EditFieldLabel = uilabel(app.UIFigure);
            app.I2EditFieldLabel.Text = 'I2';
            app.I2EditField = uieditfield(app.UIFigure, 'numeric');
            app.VLabel_2 = uilabel(app.UIFigure);
            app.VLabel_2.Text = 'V';
            app.VLabel_3 = uilabel(app.UIFigure);
            app.VLabel_3.Text = 'V';
            app.VLabel_4 = uilabel(app.UIFigure);
            app.VLabel_4.Text = 'V';
            app.VLabel_5 = uilabel(app.UIFigure);
            app.VLabel_5.Text = 'V';
            app.AmpsLabel = uilabel(app.UIFigure);
            app.AmpsLabel.Text = 'Amps';
            app.AmpsLabel_2 = uilabel(app.UIFigure);
            app.AmpsLabel_2.Text = 'Amps';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Componente_Practico

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end